classdef AppGrafica_treinoRedes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AplicaoGrficadeTreinodeRedesUIFigure  matlab.ui.Figure
        SairMenu                     matlab.ui.container.Menu
        RelatrioMenu                 matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        AnaGuedesMenu                matlab.ui.container.Menu
        GabrielDiasMenu              matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        TreinarRedeTab               matlab.ui.container.Tab
        TreinarRedeButton            matlab.ui.control.Button
        NomedaRedeEditField          matlab.ui.control.EditField
        NomedaRedeEditFieldLabel     matlab.ui.control.Label
        DesejaguardararedeCheckBox   matlab.ui.control.CheckBox
        FuncAtivaoSadaDropDown       matlab.ui.control.DropDown
        FuncAtivaoSadaDropDownLabel  matlab.ui.control.Label
        PastaDropDown                matlab.ui.control.DropDown
        PastaDropDownLabel           matlab.ui.control.Label
        NmerodepocasEditField        matlab.ui.control.NumericEditField
        NmerodepocasEditFieldLabel   matlab.ui.control.Label
        PercValidaoEditField         matlab.ui.control.NumericEditField
        PercValidaoEditFieldLabel    matlab.ui.control.Label
        PercTesteEditField           matlab.ui.control.NumericEditField
        PercTesteEditFieldLabel      matlab.ui.control.Label
        PercTreinoEditField          matlab.ui.control.NumericEditField
        TreinoLabel                  matlab.ui.control.Label
        NmerodeNeurniosEditField     matlab.ui.control.NumericEditField
        NmerodeNeurniosLabel         matlab.ui.control.Label
        FunodeAtivaoDropDown         matlab.ui.control.DropDown
        FunodeAtivaoDropDownLabel    matlab.ui.control.Label
        FunodeTreinoDropDown         matlab.ui.control.DropDown
        FunodeTreinoDropDownLabel    matlab.ui.control.Label
        CarregarRedeTab              matlab.ui.container.Tab
        NomedaRedeEditField_2        matlab.ui.control.EditField
        NomedaRedeEditFieldLabel_2   matlab.ui.control.Label
        DesejaguardarosresultadosCheckBox  matlab.ui.control.CheckBox
        ResultadosTextArea           matlab.ui.control.TextArea
        ResultadosTextAreaLabel      matlab.ui.control.Label
        AplicarRedeaoDataSetButton   matlab.ui.control.Button
        PastaDropDown_2              matlab.ui.control.DropDown
        PastaDropDown_2Label         matlab.ui.control.Label
        CarregarRedeButton           matlab.ui.control.Button
        CriarImagemTab               matlab.ui.container.Tab
        CarregarRedeButton_2         matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        LimparDesenhoButton          matlab.ui.control.Button
        ClassificarImagemButton      matlab.ui.control.Button
        CarregarImagemButton         matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        conf
        nomeRedeCarregada
        grayImage = ones(224,224);
        conf1 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            function inicializarDesenho(app)
                cla(app.UIAxes);
                app.UIAxes.XLim = [0 280];
                app.UIAxes.YLim = [0 280];
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                app.UIAxes.Box = 'on';
                app.UIAxes.Color = 'w';
                hold(app.UIAxes, 'on');
            
                % Ligar callbacks de rato
                app.UIAxes.ButtonDownFcn = @(src, event) iniciarLinha(app);
            end
            function iniciarLinha(app)
                cp = app.UIAxes.CurrentPoint;
                x = cp(1,1);
                y = cp(1,2);
                hLine = plot(app.UIAxes, x, y, 'k', 'LineWidth', 4);
                set(app.UIAxes.Parent, ...
                    'WindowButtonMotionFcn', @(~,~) arrastarLinha(app, hLine), ...
                    'WindowButtonUpFcn', @(~,~) terminarLinha(app));
            end
            
            function arrastarLinha(app, hLine)
                cp = app.UIAxes.CurrentPoint;
                x = get(hLine, 'XData');
                y = get(hLine, 'YData');
                set(hLine, 'XData', [x cp(1,1)], 'YData', [y cp(1,2)]);
            end
            
            function terminarLinha(app)
                set(app.UIAxes.Parent, ...
                    'WindowButtonMotionFcn', '', ...
                    'WindowButtonUpFcn', '');
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
           close = questdlg("Deseja realmente sair?","Sair","Sim","Não","Sim");
            if strcmp(close,"Sim")
                delete(app)
            end
        end

        % Button pushed function: TreinarRedeButton
        function TreinarRedeButtonPushed(app, event)
            function [inputData, targetData] = carregarImagens(~, pasta)
                classes = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};
                imgSize = [32, 32];
                inputData = [];
                targetData = [];
        
                for i = 1:numel(classes)
                    folder = fullfile(pasta, classes{i});
                    files = [dir(fullfile(folder, '*.png')); dir(fullfile(folder, '*.jpg'))];
        
                    for j = 1:length(files)
                        img = imread(fullfile(folder, files(j).name));
                        if size(img,3) == 3
                            img = rgb2gray(img);
                        end
                        img = imbinarize(imresize(img, imgSize));
                        img = ~img;
        
                        inputData(:, end+1) = img(:);
                        t = zeros(numel(classes),1); t(i) = 1;
                        targetData(:, end+1) = t;
                    end
                end
            end
            
            funcTreino = app.FunodeTreinoDropDown.Value;
            funcAtiv = app.FunodeAtivaoDropDown.Value;
            funcAtivSaida = app.FuncAtivaoSadaDropDown.Value;
            neur = app.NmerodeNeurniosEditField.Value;
            epocas = app.NmerodepocasEditField.Value;
            
            rTreino = app.PercTreinoEditField.Value / 100;
            rTeste = app.PercTesteEditField.Value / 100;
            rVal = app.PercValidaoEditField.Value / 100;
            
            % Validar soma das percentagens
            if abs((rTreino + rTeste + rVal) - 1) > 0.01
                uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, ...
                    'A soma das percentagens deve ser 100%.', 'Erro','Icon','error');
                return;
            end
            
            % Criar a rede
            net = feedforwardnet(neur, funcTreino);
            net.layers{1}.transferFcn = funcAtiv;
            net.layers{2}.transferFcn = funcAtivSaida;
            
            net.trainParam.epochs = epocas;
            net.divideParam.trainRatio = rTreino;
            net.divideParam.testRatio = rTeste;
            net.divideParam.valRatio = rVal;
            
            [inputs, targets] = carregarImagens(app, app.PastaDropDown.Value);

            % Treinar
            [net, tr] = train(net, inputs, targets);

            %verificar se quer guardar rede
            if app.DesejaguardararedeCheckBox.Value
                nomeFicheiro = app.NomedaRedeEditField.Value;
            
                % Validar nome
                if isempty(nomeFicheiro)
                    uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, ...
                        'Por favor introduza um nome válido para guardar a rede.', 'Aviso','Icon','warning');
                    return;
                end
            
                % Guardar rede como .mat
                nomeFicheiro = strcat(nomeFicheiro, '.mat');
                save(nomeFicheiro, 'net');
            
                uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, ...
                    ['Rede guardada como "', nomeFicheiro, '"'], 'Guardado','Icon','info');
            end
                        
            % Precisão GLOBAL
            outAll = net(inputs);
            acertosTotal = 0;
            for i = 1:size(outAll,2)
                [~, pred] = max(outAll(:,i));
                [~, real] = max(targets(:,i));
                if pred == real
                    acertosTotal = acertosTotal + 1;
                end
            end
            accTotal = acertosTotal / size(outAll,2) * 100;
            
            % Precisão TESTE
            testInputs = inputs(:, tr.testInd);
            testTargets = targets(:, tr.testInd);
            outTest = net(testInputs);
            
            acertosTeste = 0;
            for i = 1:size(outTest,2)
                [~, pred] = max(outTest(:,i));
                [~, real] = max(testTargets(:,i));
                if pred == real
                    acertosTeste = acertosTeste + 1;
                end
            end
            accTeste = acertosTeste / size(outTest,2) * 100;
            
            % Mostrar resultados
            msg = sprintf('Precisão GLOBAL: %.2f%%\nPrecisão TESTE: %.2f%%', accTotal, accTeste);
            uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, msg,'Precisões', 'Icon' ,'info');
            
            % Mostrar matriz de confusão (figura externa)
            figure;
            plotconfusion(targets, outAll);
            title('Matriz de Confusão - Conjunto Total');
        end

        % Button pushed function: CarregarRedeButton
        function CarregarRedeButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Selecionar Rede');
            if isequal(file, 0)
                return;
            end
            temp = load(fullfile(path, file));
            if isfield(temp, 'net')
                app.conf = temp.net;
                app.nomeRedeCarregada = file;
                uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, 'Rede carregada com sucesso.', 'Info','Icon','success');
            else
                uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, 'Ficheiro não contém uma rede válida.', 'Erro','Icon','error');
            end
        end

        % Button pushed function: AplicarRedeaoDataSetButton
        function AplicarRedeaoDataSetButtonPushed(app, event)
            if isempty(app.conf)
                uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, 'Por favor carregue uma rede primeiro.', 'Erro','Icon','error');
                return;
            end
        
            % Ler pasta selecionada
            pasta = app.PastaDropDown_2.Value;
            classes = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};
            imgSize = [32 32];
            numClasses = numel(classes);
            inputData = [];
            targetData = [];
        
            % Ler imagens
            for i = 1:numClasses
                folder = fullfile(pasta, classes{i});
                files = [dir(fullfile(folder, '*.png')); dir(fullfile(folder, '*.jpg'))];
        
                for j = 1:length(files)
                    img = imread(fullfile(folder, files(j).name));
                    if size(img,3) == 3
                        img = rgb2gray(img);
                    end
                    img = imbinarize(imresize(img, imgSize));
                    inputData(:, end+1) = img(:);
        
                    t = zeros(numClasses,1); t(i) = 1;
                    targetData(:, end+1) = t;
                end
            end
        
            % Aplicar a rede
            outputs = app.conf(inputData);
            [~, pred] = max(outputs);
            [~, real] = max(targetData);
            acc = sum(pred == real) / length(real) * 100;
        
            % Mostrar resultados na interface
            app.ResultadosTextArea.Value = {
                sprintf('Rede utilizada: %s', app.nomeRedeCarregada)
                sprintf('Pasta analisada: %s', pasta)
                sprintf('Total de imagens: %d', size(inputData, 2))
                sprintf('Precisão: %.2f%%', acc)
            };
        
            % Guardar resultados se solicitado
            if app.DesejaguardarosresultadosCheckBox.Value
                nome = strtrim(app.NomedaRedeEditField_2.Value);
                if isempty(nome)
                    nome = ['resultados_', pasta];
                end
                ficheiro = [nome, '.txt'];
                fid = fopen(ficheiro, 'w');
                fprintf(fid, 'Rede utilizada: %s\n', app.nomeRedeCarregada);
                fprintf(fid, 'Resultados da classificação\n');
                fprintf(fid, 'Pasta: %s\n', pasta);
                fprintf(fid, 'Imagens: %d\n', size(inputData,2));
                fprintf(fid, 'Precisão: %.2f%%\n', acc);
                fclose(fid);
                msg = sprintf('Resultados guardados em: %s', ficheiro);
                uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, msg, 'Sucesso','Icon','success');
            end
        end

        % Menu selected function: AnaGuedesMenu
        function AnaGuedesMenuSelected(app, event)
            AnaGuedes;
        end

        % Button pushed function: CarregarImagemButton
        function CarregarImagemButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.bmp'}, 'Selecionar imagem');
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            axes(app.UIAxes); 
            imshow(img, 'Parent', app.UIAxes);  % Mostra no UIAxes
        
            % Pré-processamento
            if size(img,3) == 3
                img = rgb2gray(img);
            end
            img = imbinarize(imresize(img, [32 32]));
        
            % Verifica se rede está carregada
            if isempty(app.conf1)
                uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, 'Por favor carregue uma rede primeiro.', 'Erro');
                return;
            end
        
            % Classificação
            output = app.conf1(img(:));
            [~, classeIdx] = max(output);
            classes = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};
            classePrevista = classes{classeIdx};
        
            % Mostrar resultado
            app.Label.Text = ['Classe prevista: ', classePrevista];
        end

        % Menu selected function: GabrielDiasMenu
        function GabrielDiasMenuSelected(app, event)
            GD;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            hFH = drawfreehand(app.UIAxes);
            
            
            xy = hFH.Position;
            
            delete(hFH);
            hold(app.UIAxes, 'on');
            xCoordinates = xy(:, 1);
            yCoordinates = xy(:, 2);
            plot(app.UIAxes, xCoordinates, yCoordinates, 'ro-', 'LineWidth', 2);
            
            promptMessage = sprintf('Pretende usar esta imagem?');
            titleBarCaption = 'Continuar?';
            button = questdlg(promptMessage, titleBarCaption, 'Sim', 'Cancelar', 'Sim');
            if strcmpi(button, 'Cancelar')
                cla(app.UIAxes);
                hold(app.UIAxes, 'off');
                title(app.UIAxes, "Clique no botão esquerdo do rato para desenhar");
                return;
            end
            title(app.UIAxes, "");
            
            
            imgSize = size(app.grayImage);

            for k = 1:length(xCoordinates)
                row = round(yCoordinates(k));
                column = round(xCoordinates(k));
            
                % Verifica se os índices estão dentro dos limites válidos
                if row >= 1 && row <= imgSize(1) && column >= 1 && column <= imgSize(2)
                    app.grayImage(row, column) = 0;
                end
            end
            
            app.grayImage = flipud(app.grayImage);
        end

        % Button pushed function: ClassificarImagemButton
        function ClassificarImagemButtonPushed(app, event)
            if isempty(app.conf1)
                uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, 'Por favor carregue uma rede.', 'Erro');
                return;
            end
        
            % Capturar imagem do desenho
            frame = getframe(app.UIAxes);
            img = rgb2gray(frame.cdata);
            img = imbinarize(imresize(img, [32 32]));
        
            % Aplicar rede
            output = app.conf1(img(:));
            [~, idx] = max(output);
            classes = {'circle','kite','parallelogram','square','trapezoid','triangle'};
            classe = classes{idx};
        
            app.Label.Text = ['Classe prevista: ', classe];
        end

        % Button pushed function: LimparDesenhoButton
        function LimparDesenhoButtonPushed(app, event)
            % Reaplica fundo branco, limites e estilo de desenho
            app.UIAxes.XLim = [0 280];
            app.UIAxes.YLim = [0 280];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Box = 'on';
            app.UIAxes.Color = 'w';
            hold(app.UIAxes, 'on');
        end

        % Button pushed function: CarregarRedeButton_2
        function CarregarRedeButton_2Pushed(app, event)
            [file, path] = uigetfile('*.mat', 'Selecionar Rede');
            if isequal(file, 0)
                return;
            end
            temp = load(fullfile(path, file));
            if isfield(temp, 'net')
                app.conf1 = temp.net;
                app.nomeRedeCarregada = file;
                uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, 'Rede carregada com sucesso.', 'Info','Icon','success');
            else
                uialert(app.AplicaoGrficadeTreinodeRedesUIFigure, 'Ficheiro não contém uma rede válida.', 'Erro','Icon','error');
            end
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open('AnaGuedes2023141564_P1_GabrielDias2023133076_P1.pdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AplicaoGrficadeTreinodeRedesUIFigure = uifigure('Visible', 'off');
            app.AplicaoGrficadeTreinodeRedesUIFigure.Name = 'Aplicação Gráfica de Treino de Redes';
            app.SairMenu = uimenu(app.AplicaoGrficadeTreinodeRedesUIFigure);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.RelatrioMenu = uimenu(app.AplicaoGrficadeTreinodeRedesUIFigure);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.AutoresMenu = uimenu(app.AplicaoGrficadeTreinodeRedesUIFigure);
            app.AutoresMenu.Text = 'Autores';
            app.AnaGuedesMenu = uimenu(app.AutoresMenu);
            app.AnaGuedesMenu.MenuSelectedFcn = createCallbackFcn(app, @AnaGuedesMenuSelected, true);
            app.AnaGuedesMenu.Text = 'Ana Guedes';
            app.GabrielDiasMenu = uimenu(app.AutoresMenu);
            app.GabrielDiasMenu.MenuSelectedFcn = createCallbackFcn(app, @GabrielDiasMenuSelected, true);
            app.GabrielDiasMenu.Text = 'Gabriel Dias';
            app.TabGroup = uitabgroup(app.AplicaoGrficadeTreinodeRedesUIFigure);
            app.TreinarRedeTab = uitab(app.TabGroup);
            app.TreinarRedeTab.Title = 'Treinar Rede';
            app.FunodeTreinoDropDownLabel = uilabel(app.TreinarRedeTab);
            app.FunodeTreinoDropDownLabel.Text = 'Função de Treino:';
            app.FunodeTreinoDropDown = uidropdown(app.TreinarRedeTab);
            app.FunodeTreinoDropDown.Items = {'trainlm', 'trainbfg', 'trainbfgc', 'trainbr', 'trainbu', 'trainc', 'traincgb', 'traincgf', 'traincgp', 'traingd', 'traingda', 'traingdm', 'traingdx', 'trainoss', 'trainr', 'trainrp', 'trainru', 'trains', 'trainscg'};
            app.FunodeTreinoDropDown.Value = 'trainlm';
            app.FunodeAtivaoDropDownLabel = uilabel(app.TreinarRedeTab);
            app.FunodeAtivaoDropDownLabel.Text = 'Função de Ativação:';
            app.FunodeAtivaoDropDown = uidropdown(app.TreinarRedeTab);
            app.FunodeAtivaoDropDown.Items = {'tansig', 'logsig', 'purelin', 'softmax', 'compet', 'hardlim', 'hardlims', 'netinv', 'poslin', 'radbas', 'radbasn', 'satlin', 'satlins', 'tribas'};
            app.FunodeAtivaoDropDown.Value = 'tansig';
            app.NmerodeNeurniosLabel = uilabel(app.TreinarRedeTab);
            app.NmerodeNeurniosLabel.Text = 'Número de Neurónios: ';
            app.NmerodeNeurniosEditField = uieditfield(app.TreinarRedeTab, 'numeric');
            app.NmerodeNeurniosEditField.Limits = [1 25];
            app.NmerodeNeurniosEditField.Value = 10;
            app.TreinoLabel = uilabel(app.TreinarRedeTab);
            app.TreinoLabel.Text = 'Perc. Treino:';
            app.PercTreinoEditField = uieditfield(app.TreinarRedeTab, 'numeric');
            app.PercTreinoEditField.Limits = [0 100];
            app.PercTreinoEditField.Value = 70;
            app.PercTesteEditFieldLabel = uilabel(app.TreinarRedeTab);
            app.PercTesteEditFieldLabel.Text = 'Perc. Teste:';
            app.PercTesteEditField = uieditfield(app.TreinarRedeTab, 'numeric');
            app.PercTesteEditField.Limits = [0 100];
            app.PercTesteEditField.Value = 15;
            app.PercValidaoEditFieldLabel = uilabel(app.TreinarRedeTab);
            app.PercValidaoEditFieldLabel.Text = 'Perc. Validação:';
            app.PercValidaoEditField = uieditfield(app.TreinarRedeTab, 'numeric');
            app.PercValidaoEditField.Limits = [0 100];
            app.PercValidaoEditField.Value = 15;
            app.NmerodepocasEditFieldLabel = uilabel(app.TreinarRedeTab);
            app.NmerodepocasEditFieldLabel.Text = 'Número de Épocas: ';
            app.NmerodepocasEditField = uieditfield(app.TreinarRedeTab, 'numeric');
            app.NmerodepocasEditField.Limits = [25 1000];
            app.NmerodepocasEditField.Value = 100;
            app.PastaDropDownLabel = uilabel(app.TreinarRedeTab);
            app.PastaDropDownLabel.Text = 'Pasta:';
            app.PastaDropDown = uidropdown(app.TreinarRedeTab);
            app.PastaDropDown.Items = {'start', 'train', 'test'};
            app.PastaDropDown.Value = 'train';
            app.FuncAtivaoSadaDropDownLabel = uilabel(app.TreinarRedeTab);
            app.FuncAtivaoSadaDropDownLabel.Text = 'Func. Ativação Saída:';
            app.FuncAtivaoSadaDropDown = uidropdown(app.TreinarRedeTab);
            app.FuncAtivaoSadaDropDown.Items = {'purelin', 'logsig', 'softmax', 'tansig', 'compet', 'hardlim', 'hardlims', 'netinv', 'poslin', 'radbas', 'radbasn', 'satlin', 'satlins', 'tribas'};
            app.FuncAtivaoSadaDropDown.Value = 'purelin';
            app.DesejaguardararedeCheckBox = uicheckbox(app.TreinarRedeTab);
            app.DesejaguardararedeCheckBox.Text = 'Deseja guardar a rede?';
            app.NomedaRedeEditFieldLabel = uilabel(app.TreinarRedeTab);
            app.NomedaRedeEditFieldLabel.Text = 'Nome da Rede:';
            app.NomedaRedeEditField = uieditfield(app.TreinarRedeTab, 'text');
            app.TreinarRedeButton = uibutton(app.TreinarRedeTab, 'push');
            app.TreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedeButtonPushed, true);
            app.TreinarRedeButton.Text = 'Treinar Rede';
            app.CarregarRedeTab = uitab(app.TabGroup);
            app.CarregarRedeTab.Title = 'Carregar Rede';
            app.CarregarRedeButton = uibutton(app.CarregarRedeTab, 'push');
            app.CarregarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarRedeButtonPushed, true);
            app.CarregarRedeButton.Text = 'Carregar Rede';
            app.PastaDropDown_2Label = uilabel(app.CarregarRedeTab);
            app.PastaDropDown_2Label.Text = 'Pasta:';
            app.PastaDropDown_2 = uidropdown(app.CarregarRedeTab);
            app.PastaDropDown_2.Items = {'start', 'train', 'test'};
            app.PastaDropDown_2.Value = 'train';
            app.AplicarRedeaoDataSetButton = uibutton(app.CarregarRedeTab, 'push');
            app.AplicarRedeaoDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarRedeaoDataSetButtonPushed, true);
            app.AplicarRedeaoDataSetButton.Text = 'Aplicar Rede ao DataSet';
            app.ResultadosTextAreaLabel = uilabel(app.CarregarRedeTab);
            app.ResultadosTextAreaLabel.Text = 'Resultados:';
            app.ResultadosTextArea = uitextarea(app.CarregarRedeTab);
            app.ResultadosTextArea.Editable = 'off';
            app.DesejaguardarosresultadosCheckBox = uicheckbox(app.CarregarRedeTab);
            app.DesejaguardarosresultadosCheckBox.Text = 'Deseja guardar os resultados?';
            app.NomedaRedeEditFieldLabel_2 = uilabel(app.CarregarRedeTab);
            app.NomedaRedeEditFieldLabel_2.Text = 'Nome:';
            app.NomedaRedeEditField_2 = uieditfield(app.CarregarRedeTab, 'text');
            app.CriarImagemTab = uitab(app.TabGroup);
            app.CriarImagemTab.Title = 'Criar Imagem';
            app.UIAxes = uiaxes(app.CriarImagemTab);
            title(app.UIAxes, 'Clique no botão esquerdo do rato para desenhar')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CarregarImagemButton = uibutton(app.CriarImagemTab, 'push');
            app.CarregarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarImagemButtonPushed, true);
            app.CarregarImagemButton.Text = 'Carregar Imagem';
            app.ClassificarImagemButton = uibutton(app.CriarImagemTab, 'push');
            app.ClassificarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificarImagemButtonPushed, true);
            app.ClassificarImagemButton.Text = 'Classificar Imagem';
            app.LimparDesenhoButton = uibutton(app.CriarImagemTab, 'push');
            app.LimparDesenhoButton.ButtonPushedFcn = createCallbackFcn(app, @LimparDesenhoButtonPushed, true);
            app.LimparDesenhoButton.Text = 'Limpar Desenho';
            app.Label = uilabel(app.CriarImagemTab);
            app.Label.Text = '';
            app.CarregarRedeButton_2 = uibutton(app.CriarImagemTab, 'push');
            app.CarregarRedeButton_2.ButtonPushedFcn = createCallbackFcn(app, @CarregarRedeButton_2Pushed, true);
            app.CarregarRedeButton_2.Text = 'Carregar Rede';
            % Show the figure after all components are created
            app.AplicaoGrficadeTreinodeRedesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppGrafica_treinoRedes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AplicaoGrficadeTreinodeRedesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AplicaoGrficadeTreinodeRedesUIFigure)
        end
    end
end